classdef PathFinder < matlab.ui.componentcontainer.ComponentContainer

    % Properties that correspond to underlying components
    properties (Access = private, Transient, NonCopyable)
        MainGrid       matlab.ui.container.GridLayout
        Label          matlab.ui.control.Label
        BrowseButton   matlab.ui.control.Button
        PathEditField  matlab.ui.control.EditField
    end

    % Events with associated public callbacks
    events (HasCallbackProperty, NotifyAccess = private)
        UiPathUpdated % Triggered when the path is updated through UI
    end

    % WARNING the context menu is really buggy and likely broken ( FIXME )
    
    properties (AbortSet, Access = public, GetObservable, SetObservable)
        % IMPORTANT NOTE to keep comp flexible between folders and files
        % the general term refering to the location of the folder or file
        % is given by "path"

        % general
        path          (1,1) string  = ""       % current path to folder/file
        label         (1,1) string  = "Label"  % label used in UI element
        buttonText    (1,1) string  = "Browse" % text found on button element
        useButtonIcon (1,1) logical = false    % enable to turn on button icon found in same folder as the component code
        buttonSize    (1,1) double  = 70       % width of the button
        pathMode      (1,1) string {mustBeMember(pathMode,{'file','folder'})} = "folder" % determines if browsing for files or folders
        modeLock      (1,1) matlab.lang.OnOffSwitchState = 'off'   % set to lock pathMode so user can not switch
        disableContextMenu (1,1) logical = true                   % set to turn off self-assigned context menu (can't be turned back on/off)
        warnInvalidPath    (1,1) logical = true                    % set to warn with uigetpref if path was set to a non-existing file/folder TODO
        warnInvalidExt     (1,1) logical = true                    % set to warn with uigetpref if file has wrong extension according to fileFilter

        % uiget general options
        uiTitle         (1,1) string = "Select {path}"    % given as title to uigetdir/file, {path} is replaced with comp.pathMode
        useStartingPath (1,1) logical = true              % determines if startingPath will be used (see below) else will use comp.path as startingPath
        startingPath    (1,1) string {mustBeFolder} = pwd % given as path when searching for a file/folder (ui will open to it)

        % uigetfile options
        fileFilter       {mustBeText} = ""                          % used as the filter argument in uigetfile (must only use *.ext formats)
        fileUseDefName   (1,1) = false                              % determines if uses comp.fileDefName or previous oldPath
        fileDefName      (1,1) = ""                                 % used as the filename for the defname argument in uigetfile
        fileMultiSelect  (1,1) matlab.lang.OnOffSwitchState = 'off' % used as the mode argument in uigetfile
    end

    properties (Dependent, SetAccess = private)
        isPathDir % determines if path is a folder (true) or file (false)
    end

    properties (Access = private)
        helperMenu     (1,1) matlab.ui.container.ContextMenu % context menu to allow user to edit some settings on the fly
        buttonIconPath (1,1) string % location of browse button icon
        randID         (1,1) uint64 % random id number assigned allowing multiple prefernces regarding
    end

    properties (Constant)
        prefGroup (1,1) string = "PathFinderComp" % prefernce group for component
    end

    methods (Access = public)
        function delete(comp)
            % called on deletion
            if ispref(comp.prefGroup, strcat('askExist_',comp.randID))
                rmpref(comp.prefGroup, strcat('askExist_',comp.randID))
            end
            if ispref(comp.prefGroup, strcat('askExtension_',comp.randID))
                rmpref(comp.prefGroup, strcat('askExtension_',comp.randID))
            end
        end
    end
    
    methods (Access = private)
        % Context menu functions
        function parent = findParentFigure(comp, child)
            try
                parent = get(child,'Parent');
                if ~isa(parent, 'matlab.ui.Figure')
                    parent = comp.findParentFigure(parent);
                end
            catch ME
                parent = child;
                if strcmp(ME.identifier, 'MATLAB:noSuchMethodOrField')
                    warning("Failed to find a matlab.ui.Figure as a parent. Outputting 'grandest' parent (likely useless).")
                end
            end
        end

        function switchMode(comp,~,~)
            % flips from file to folder mode and vice versa called from
            % context menu
            switch comp.pathMode
                case 'file'
                    comp.pathMode = 'folder';
                    comp.helperMenu.Children(1).Text = 'Switch to File Search';
                case 'folder'
                    comp.pathMode = 'file';
                    comp.helperMenu.Children(1).Text = 'Switch to Folder Search';
            end
        end

        function resetAskPreferences(comp,~,~)
            % resets the warning preference 
            setpref(comp.prefGroup, strcat('askExist_',comp.randID), 'ask')
            setpref(comp.prefGroup, strcat('askExtension_',comp.randID), 'ask')
        end

        % Helper functions
        function bool = isTextEmpty(comp, text)
            switch class(text)
                case 'char'
                    bool = isempty(text);
                case 'string'
                    bool = isempty(text) || all(strip(text) == "");
                case 'cell'
                    bool = isempty(text) || all(cellfun(@(x) comp.isTextEmpty(x), text));
            end
        end

        function focusApp(comp)
            % makes the parent figure of this app the active window again
            drawnow; figure(comp.findParentFigure(comp))
        end
    end

    methods % set and get methods
        function val = get.randID(comp)
            % randID is stored as uint64 but outputs a text -> ID_{num}
            val = sprintf('ID_%i',comp.randID);
        end
        
        function val = get.isPathDir(comp)
            % determines if comp.path is a folder (true) or file (false)
            info = dir(strcat(comp.path,'*'));
            if comp.isTextEmpty(comp.path)
                val = true;
            elseif isempty(info)
                [~,~,ext] = fileparts(comp.path);
                val = isempty(ext);
            elseif length(info) == 1
                val = info.isdir;
            else
                error('Don''t know what is going on with comp.path. Failed to figure out if the path is a folder or file.')
            end
        end
       
        function set.fileFilter(comp, val)
            % must be string char or cell otherwise expect coder to know
            % how to set filter to work with uigetfile
            if ~strcmp(class(val),{'char','string','cell'})
                errID = mfilename+":invalidFileFilterType";
                errMsg= "The fileFilter property has to be a string, character array, or cell array of character arrays.";
                throw(MException(errID, errMsg))
            end
            comp.fileFilter = val;
        end

        function set.fileDefName(comp, val)
            % ensure this is only a name with a extension but no path
            [folder, ~, ~] = fileparts(val);
            if ~isempty(folder)
                errID = mfilename+":invalidFileDefName";
                errMsg= "The fileDefName property can not have a folder path specified. Give only the file name.";
                throw(MException(errID, errMsg))
            end
            comp.fileDefName = folder;
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function postSetupFcn(comp)
            % create a context menu
            if ~comp.disableContextMenu
                fig = comp.findParentFigure(comp);
                comp.helperMenu = uicontextmenu(fig);
                uimenu(comp.helperMenu, 'Text', 'Switch to File Search', ...
                                    'UserData', comp, ...
                             'MenuSelectedFcn', @comp.switchMode)
                uimenu(comp.helperMenu, 'Text', 'Reset preferences', ...
                                    'UserData', comp, ...
                             'MenuSelectedFcn', @comp.resetAskPreferences)
                comp.MainGrid.ContextMenu = comp.helperMenu;
                %comp.BrowseButton.ContextMenu = comp.helperMenu;
                %comp.BrowseButton.ContextMenu = comp.helperMenu;
            end

            % assing a random id
            comp.randID = uint64(randi(-1+2^53,[1 1]));

            % find the buttonIcon location
            comp.buttonIconPath = fullfile(fileparts(mfilename('fullpath')), 'browseIcon.png');
        end

        % Value changed function: PathEditField
        function PathEditFieldValueChanged(comp, event)
            % get old and new path
            oldPath = comp.path;
            newPath = comp.PathEditField.Value;
            
            % check if new path is different and valid
            if strcmp(oldPath, newPath)
                return % path is the same do nothing
            elseif strcmp(comp.pathMode, 'folder') % in folder mode
                % check if folder exists
                if ~exist(newPath, 'dir') && isNotOk_existence(comp)
                    comp.PathEditField.Value = oldPath;
                    return
                end

            elseif strcmp(comp.pathMode, 'file') % in file mode
                % if file lacks extension search for file to autocomplete
                [~,~,ext] = fileparts(newPath);
                if isempty(ext)
                    possibleFiles = dir(strcat(newPath,'*'));
                    if length(possibleFiles) == 1 && ~possibleFiles.isdir
                        newPath = fullfile(possibleFiles.folder, possibleFiles.name);
                    elseif length(possibleFiles) > 1
                        % multiple possible files ask user to select
                        possibleFiles([possibleFiles.isdir]) = [];
                        selected = listdlg("ListString", {possibleFiles.name}, ...
                                                "SelectionMode", "single",             ...
                                                     "ListSize", [500 200], ...
                                                         "Name", "Select File",        ...
                                                 "PromptString", "Multiple files found (no extension was given)");
                        if isempty(selected)
                            comp.PathEditField.Value = oldPath;
                            return % user canceled out
                        end
                        newPath = fullfile(possibleFiles.folder, possibleFiles(selected).name);
                    end
                end
                % check if file has the right extension based on comp.fileFilter
                fileExtensionMatches = comp.isTextEmpty(comp.fileFilter) || contains(comp.fileFilter,'.*') || ...
                                       any( contains(comp.fileFilter,regexpPattern("\*"+ext+"(\W|$)")) );
                if ~fileExtensionMatches && isNotOk_extension(comp)
                    comp.PathEditField.Value = oldPath;
                    return
                end
                % check if file exists
                if ~exist(newPath, 'file') && isNotOk_existence(comp)
                    comp.PathEditField.Value = oldPath;
                    return
                end
            end

            % update ui elements (note comp.PathEditField.Value already set to newPath)
            comp.PathEditField.Tooltip = newPath;

            % update path and notify event
            comp.path = newPath;
            notify(comp, 'UiPathUpdated');

            % helper function for getting preferences
            function answer = isNotOk_existence(comp)
                if ~comp.warnInvalidPath
                    answer = false;
                    return
                end
                pref = strcat('askExist_',comp.randID);
                title = sprintf('Invalid %s',comp.pathMode);
                quest = sprintf('The %s does not exist, set the path anyway?',comp.pathMode);
                switch lower(uigetpref(comp.prefGroup,pref,title,quest,{'Yes','No'},'DefaultButton','No'))
                    case 'yes'
                        answer = false;
                    case 'no'
                        answer = true;
                    otherwise
                        answer = true;
                end
            end

            function answer = isNotOk_extension(comp)
                if ~comp.warnInvalidExt
                    answer = false;
                    return
                end
                pref = strcat('askExtension_',comp.randID);
                title = "Invalid File Extension";
                validExtensions = comp.fileFilter; %TODO make this work
                quest = sprintf("The file extension does not match expected extensions set [%s], set path anyway?",validExtensions);
                switch lower(uigetpref(comp.prefGroup,pref,title,quest,{'Yes','No'},'DefaultButton','No'))
                    case 'yes'
                        answer = false;
                    case 'no'
                        answer = true;
                    otherwise
                        answer = true;
                end
            end
        end

        % Button pushed function: BrowseButton
        function BrowseButtonPushed(comp, event)
            % User requested the ui file selector to be used to search for
            % new folder or file to replace current path
            
            % set general options
            titleText = strrep(comp.uiTitle, '{path}', comp.pathMode);
            if comp.useStartingPath
                oldFolder = comp.startingPath;
            else
                if comp.isPathDir
                    oldFolder = comp.path;
                    oldFile = ''; oldExt = '';
                else
                    [oldFolder,oldFile,oldExt] = fileparts(comp.path);
                end
            end

            % determine which mode and get file/folder
            switch comp.pathMode
                case 'file'
                    % set defName
                    if comp.fileUseDefName
                        defName = fullfile(oldFolder, comp.fileDefName);
                    else
                        defName = fullfile(oldFolder, strcat(oldFile,oldExt));
                    end
                    % call uigetfile and refocus app 
                    [file,folder] = uigetfile(comp.fileFilter, titleText, defName, "MultiSelect", comp.fileMultiSelect);
                    newPath = fullfile(folder,file);
                    comp.focusApp()

                case 'folder'
                    % call uigetdir and refocus app 
                    newPath = uigetdir(oldFolder, titleText);
                    comp.focusApp()
            end

            % only set and notify if newPath is different and valid
            if ~(isnumeric(newPath) || strcmp(comp.path, newPath))
                % set the path
                comp.path = newPath;
                % notify path update
                notify(comp, 'UiPathUpdated');
            end
        end
    end

    methods (Access = protected)
        
        % Code that executes when the value of a public property is changed
        function update(comp)
            % Update UI elements based on property values

            % update label
            comp.Label.Text = comp.label;

            % update path
            comp.PathEditField.Value = comp.path;
            comp.PathEditField.Tooltip = comp.path;

            % update button
            comp.BrowseButton.Text = comp.buttonText;
            if comp.useButtonIcon
                comp.BrowseButton.Icon = comp.buttonIconPath;
            else
                comp.BrowseButton.Icon = "";
            end
            comp.MainGrid.ColumnWidth{2} = comp.buttonSize;

            % update context menu
            if ~isempty(comp.helperMenu.Children)
                comp.helperMenu.Children(1).Enable = comp.modeLock;
            end
        end

        % Create the underlying components
        function setup(comp)
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            comp.Position = [1 1 410 70];
            comp.BackgroundColor = [0.94 0.94 0.94];
            comp.MainGrid = uigridlayout(comp);
            comp.PathEditField = uieditfield(comp.MainGrid, 'text');
            comp.PathEditField.ValueChangedFcn = matlab.apps.createCallbackFcn(comp, @PathEditFieldValueChanged, true);
            comp.BrowseButton = uibutton(comp.MainGrid, 'push');
            comp.BrowseButton.ButtonPushedFcn = matlab.apps.createCallbackFcn(comp, @BrowseButtonPushed, true);
            comp.BrowseButton.Tooltip = {'Browse using the file explorer UI'};
            comp.BrowseButton.Text = 'Browse';
            comp.Label = uilabel(comp.MainGrid);
            % Execute the startup function
            postSetupFcn(comp)
        end
    end
end